classdef DeskAPP_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure          matlab.ui.Figure
        autoSel               matlab.ui.control.Button
        clearMaker            matlab.ui.control.Button
        selIndex              matlab.ui.control.Button
        OutputDataLabel       matlab.ui.control.Label
        draw3D                matlab.ui.control.Button
        clearData             matlab.ui.control.Button
        clearModel            matlab.ui.control.Button
        selList               matlab.ui.control.ListBox
        Label_6               matlab.ui.control.Label
        printImg              matlab.ui.control.Button
        addSel_5              matlab.ui.control.Button
        addSel_4              matlab.ui.control.Button
        addSel_3              matlab.ui.control.Button
        addSel_2              matlab.ui.control.Button
        addSel_1              matlab.ui.control.Button
        RawDataLabel          matlab.ui.control.Label
        LabelData             matlab.ui.control.Label
        PrvGroup              matlab.ui.control.Button
        NextGroup             matlab.ui.control.Button
        testDataNumNow        matlab.ui.control.Label
        CurrentLocationLabel  matlab.ui.control.Label
        testDataNum           matlab.ui.control.Label
        DataVolumeLabel       matlab.ui.control.Label
        importTestData        matlab.ui.control.Button
        ModelList             matlab.ui.control.ListBox
        Label                 matlab.ui.control.Label
        importModel           matlab.ui.control.Button
        UIAxe_out_5           matlab.ui.control.UIAxes
        UIAxe_out_4           matlab.ui.control.UIAxes
        UIAxe_out_3           matlab.ui.control.UIAxes
        UIAxe_out_2           matlab.ui.control.UIAxes
        UIAxe_out_1           matlab.ui.control.UIAxes
        UIAxe_raw_5           matlab.ui.control.UIAxes
        UIAxe_raw_4           matlab.ui.control.UIAxes
        UIAxe_raw_3           matlab.ui.control.UIAxes
        UIAxe_raw_2           matlab.ui.control.UIAxes
        UIAxe_raw_1           matlab.ui.control.UIAxes
        UIAxe_true_5          matlab.ui.control.UIAxes
        UIAxe_true_4          matlab.ui.control.UIAxes
        UIAxe_true_3          matlab.ui.control.UIAxes
        UIAxe_true_2          matlab.ui.control.UIAxes
        UIAxe_true_1          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modelSelNow %当前选择的模型
        testdata %测试数据，是一个表格
        dataIndex %当前显示的数据
        datanum %测试数据量
        maker
    end
    
    methods (Access = private)
        
        function results = updataMash(app,dataindex)
            %输入当前索引，刷新后索引要更改，还要判断是否还够5个
            %显示真实图
            
            if app.datanum-dataindex<5
                dataindex=app.datanum-4;
            end
            results=dataindex+5;
            mesh(app.UIAxe_true_1,app.testdata{dataindex,2}{1});
            view(app.UIAxe_true_1,[0,-90]);
            colormap(app.UIAxe_true_1,"turbo");
            mesh(app.UIAxe_true_2,app.testdata{dataindex+1,2}{1});
            view(app.UIAxe_true_2,[0,-90]);
            colormap(app.UIAxe_true_2,"turbo");
            mesh(app.UIAxe_true_3,app.testdata{dataindex+2,2}{1});
            view(app.UIAxe_true_3,[0,-90]);
            colormap(app.UIAxe_true_3,"turbo");
            mesh(app.UIAxe_true_4,app.testdata{dataindex+3,2}{1});
            view(app.UIAxe_true_4,[0,-90]);
            colormap(app.UIAxe_true_4,"turbo");
            mesh(app.UIAxe_true_5,app.testdata{dataindex+4,2}{1});
            view(app.UIAxe_true_5,[0,-90]);
            colormap(app.UIAxe_true_5,"turbo");
            
            %显示原始图
            mesh(app.UIAxe_raw_1,app.testdata{dataindex,1}{1});
            view(app.UIAxe_raw_1,[0,-90]);
            colormap(app.UIAxe_raw_1,"turbo");
            mesh(app.UIAxe_raw_2,app.testdata{dataindex+1,1}{1});
            view(app.UIAxe_raw_2,[0,-90]);
            colormap(app.UIAxe_raw_2,"turbo");
            mesh(app.UIAxe_raw_3,app.testdata{dataindex+2,1}{1});
            view(app.UIAxe_raw_3,[0,-90]);
            colormap(app.UIAxe_raw_3,"turbo");
            mesh(app.UIAxe_raw_4,app.testdata{dataindex+3,1}{1});
            view(app.UIAxe_raw_4,[0,-90]);
            colormap(app.UIAxe_raw_4,"turbo");
            mesh(app.UIAxe_raw_5,app.testdata{dataindex+4,1}{1});
            view(app.UIAxe_raw_5,[0,-90]);
            colormap(app.UIAxe_raw_5,"turbo");
            
            %显示重构图
            mesh(app.UIAxe_out_1,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
            view(app.UIAxe_out_1,[0,-90]);
            colormap(app.UIAxe_out_1,"turbo");
            mesh(app.UIAxe_out_2,app.modelSelNow.predict(app.testdata{dataindex+1,1}{1}));
            view(app.UIAxe_out_2,[0,-90]);
            colormap(app.UIAxe_out_2,"turbo");
            mesh(app.UIAxe_out_3,app.modelSelNow.predict(app.testdata{dataindex+2,1}{1}));
            view(app.UIAxe_out_3,[0,-90]);
            colormap(app.UIAxe_out_3,"turbo");
            mesh(app.UIAxe_out_4,app.modelSelNow.predict(app.testdata{dataindex+3,1}{1}));
            view(app.UIAxe_out_4,[0,-90]);
            colormap(app.UIAxe_out_4,"turbo");
            mesh(app.UIAxe_out_5,app.modelSelNow.predict(app.testdata{dataindex+4,1}{1}));
            view(app.UIAxe_out_5,[0,-90]);
            colormap(app.UIAxe_out_5,"turbo");
            
            app.testDataNumNow.Text=num2str(dataindex+5);
            
        end
        
        function results = addmaker(app,btn,dataindex)
            %输入按钮号，输入当前索引，返回maker
            %当前索引是显示结果的最大+1
            switch (btn)
                case 1
                    selmaker=dataindex-5;
                case 2
                    selmaker=dataindex-4;
                case 3
                    selmaker=dataindex-3;
                case 4
                    selmaker=dataindex-2;
                case 5
                    selmaker=dataindex-1;
            end
            
            %如果里面没有重复的再加入
            %if isempty(find(app.maker==selmaker))
                app.selList.Items{end+1}=num2str(selmaker);%不论里面有没有都可以在后面加一个；
                results=selmaker;
            %end
            %results=-1;
        end
        
        function results = makename(~,path,makernum,flag)
            %flag，0清晰图，1原始图，2重构图
            switch (flag)
                case 0
                    results=strcat(path,{'\clear_'},num2str(makernum),'.tif');
                case 1
                    results=strcat(path,{'\raw_'},num2str(makernum),'.tif');
                case 2
                    results=strcat(path,{'\restore_'},num2str(makernum),'.tif');
            end
            return;
        end
        
        function results = printimg(app,path,dataindex,flag)
            h=figure('visible','off');
            ax=axes;
            switch (flag)
                case 0%清晰图
                    mesh(ax,app.testdata{dataindex,2}{1});
                case 1%模糊图
                    mesh(ax,app.testdata{dataindex,1}{1});
                case 2%重构图
                    mesh(ax,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
            end
            set(gca,'FontSize',22);
            view(0,-90);
            colormap(h, 'turbo');
            path=app.makename(path,num2str(dataindex),flag);
            print(h,path{1},'-dtiffn','-r600');
            results=0;
        end
        
        function results = updataMashOne(app,aix1,aix2,aix3,dataindex)

            results=0;
            mesh(aix1,app.testdata{dataindex,2}{1});
            view(aix1,[0,-90]);
            colormap(aix1,"turbo");
            
            %显示原始图
            mesh(aix2,app.testdata{dataindex,1}{1});
            view(aix2,[0,-90]);
            colormap(aix2,"turbo");
            
            
            %显示重构图
            mesh(aix3,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
            view(aix3,[0,-90]);
            colormap(aix3,"turbo");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.modelSelNow=0;%当前选中的模型
            app.testdata=0;%测试数据，是一个表格
            app.dataIndex=1;
            app.datanum=0;
            app.maker=[];
        end

        % Button pushed function: importModel
        function importModelPushed(app, event)
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","Select the model of the workspace", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            k=val(sel);
            %选好的模型放在list列表框里
            nowlist=app.ModelList.Items;
            if isempty(nowlist)
                app.ModelList.Items=k;
            else
                temp=app.ModelList.Items;
                app.ModelList.Items=[temp k'];
            end
            app.ModelList.Value=app.ModelList.Items(1);
            app.modelSelNow=evalin('base',app.ModelList.Items{1});
        end

        % Button pushed function: clearModel
        function clearModelButtonPushed(app, event)
            app.ModelList.Items={};
        end

        % Value changed function: ModelList
        function ModelListValueChanged(app, event)
            value = app.ModelList.Value;
            app.modelSelNow=evalin('base',value);
        end

        % Button pushed function: draw3D
        function draw3DButtonPushed(app, event)
            if app.testDataNum==0 || isempty(app.ModelList.Items)
                msgbox('需要导入数据或模型。','消息提示',"modal");
                return;
            end
            app.dataIndex=app.dataIndex-5;
            
            app.dataIndex=app.updataMash(app.dataIndex);
            app.testDataNumNow.Text=num2str(app.dataIndex);
            
        end

        % Button pushed function: importTestData
        function importTestDataButtonPushed(app, event)
            %只能导入一个表，由另一个软件做的
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择工作区中的模型", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            app.testdata=evalin('base',val{sel});
            temp=size(app.testdata);
            app.testDataNum.Text=num2str(temp(1));%数据总数
            app.datanum=temp(1);
            app.testDataNumNow.Text=num2str(1);%显示当前的索引
            app.dataIndex=1;%当前的索引
            
            app.dataIndex=app.updataMash(app.dataIndex);
            
        end

        % Button pushed function: NextGroup
        function NextGroupButtonPushed(app, event)
            if app.dataIndex>app.testDataNum %index会指向下一个，如果比最大值大一个，说明到最后了
                app.dataIndex=app.testDataNum-4;
            end
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: PrvGroup
        function PrvGroupButtonPushed(app, event)
            if app.dataIndex>app.testDataNum
                app.dataIndex=app.dataIndex-5-mod(app.dataIndex,5);
            elseif app.dataIndex==6
                app.dataIndex=1;
            else
                app.dataIndex=app.dataIndex-10;
            end
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: selIndex
        function selIndexButtonPushed(app, event)
            prompt = {'跳转到第几个数据:'};
            dlgtitle = 'Input';
            dims = [1 5];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return;
            end
            num=str2double(answer{1});
            if num>app.testDataNum || num<0
                msgbox('超出了选取范围。','消息提示',"modal");
                return;
            end

            app.dataIndex=num;
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: addSel_1
        function addSel_1ButtonPushed(app, event)
            %temp=app.addmaker(1,app.dataIndex);
            %if temp~=-1
                app.maker(end+1)=app.addmaker(1,app.dataIndex);
            %end
        end

        % Button pushed function: addSel_2
        function addSel_2ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(2,app.dataIndex);
        end

        % Button pushed function: addSel_3
        function addSel_3ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(3,app.dataIndex);
        end

        % Button pushed function: addSel_4
        function addSel_4ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(4,app.dataIndex);
        end

        % Button pushed function: addSel_5
        function addSel_5ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(5,app.dataIndex);
        end

        % Button pushed function: clearMaker
        function clearMakerButtonPushed(app, event)
            app.maker=[];
            app.selList.Items={};
        end

        % Button pushed function: printImg
        function printImgButtonPushed(app, event)
            imgsavepath=uigetdir('D:\Work_F\CNNFyj','选择保存文件夹');
            if imgsavepath==0
                return;
            end
            
            for i=1:length(app.maker)
                %保存清晰图
                app.printimg(imgsavepath,app.maker(i),0);
                %保存原始图
                app.printimg(imgsavepath,app.maker(i),1);
                %保存修正图 
                app.printimg(imgsavepath,app.maker(i),2);
            end

            
        end

        % Button pushed function: autoSel
        function autoSelButtonPushed(app, event)
            %所有图像重构，mes评价，显示出plot
            datasize=size(app.testdata);
            res=zeros(datasize(1),2);
            w = waitbar(0,'0%','Name','正在处理...');
            for i=1:datasize(1)
                res(i,:)=imgMseUI(app.testdata{i,2}{1},app.modelSelNow.predict(app.testdata{i,1}{1}));
                waitbar(i/datasize(1),w,sprintf('%d / %d',i,datasize(1)));
            end
            delete(w);
            figure;
            plot(res(:,1));
            
            %排序
            indextemp=[1:datasize(1)];
            mse=res(:,1);
            res=sortrows([mse indextemp']);
            ressize=size(res);
            
            app.updataMashOne(app.UIAxe_true_1,app.UIAxe_raw_1,app.UIAxe_out_1,res(ressize(1)-4,1));
            app.updataMashOne(app.UIAxe_true_1,app.UIAxe_raw_1,app.UIAxe_out_1,res(ressize(1)-3,1));
            app.updataMashOne(app.UIAxe_true_1,app.UIAxe_raw_1,app.UIAxe_out_1,res(ressize(1)-2,1));
            app.updataMashOne(app.UIAxe_true_1,app.UIAxe_raw_1,app.UIAxe_out_1,res(ressize(1)-1,1));
            app.updataMashOne(app.UIAxe_true_1,app.UIAxe_raw_1,app.UIAxe_out_1,res(ressize(1),1));
            %选出最小的5个图，显示在UI
            %询问是否打印，是
            %否，选出次小的5个，显示，是否打印？
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.UIAxe_true_1 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_true_1, 'Title')
            xlabel(app.UIAxe_true_1, 'X')
            ylabel(app.UIAxe_true_1, 'Y')
            zlabel(app.UIAxe_true_1, 'Z')
            app.UIAxe_true_2 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_true_2, 'Title')
            xlabel(app.UIAxe_true_2, 'X')
            ylabel(app.UIAxe_true_2, 'Y')
            zlabel(app.UIAxe_true_2, 'Z')
            app.UIAxe_true_3 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_true_3, 'Title')
            xlabel(app.UIAxe_true_3, 'X')
            ylabel(app.UIAxe_true_3, 'Y')
            zlabel(app.UIAxe_true_3, 'Z')
            app.UIAxe_true_4 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_true_4, 'Title')
            xlabel(app.UIAxe_true_4, 'X')
            ylabel(app.UIAxe_true_4, 'Y')
            zlabel(app.UIAxe_true_4, 'Z')
            app.UIAxe_true_5 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_true_5, 'Title')
            xlabel(app.UIAxe_true_5, 'X')
            ylabel(app.UIAxe_true_5, 'Y')
            zlabel(app.UIAxe_true_5, 'Z')
            app.UIAxe_raw_1 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_raw_1, 'Title')
            xlabel(app.UIAxe_raw_1, 'X')
            ylabel(app.UIAxe_raw_1, 'Y')
            zlabel(app.UIAxe_raw_1, 'Z')
            app.UIAxe_raw_2 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_raw_2, 'Title')
            xlabel(app.UIAxe_raw_2, 'X')
            ylabel(app.UIAxe_raw_2, 'Y')
            zlabel(app.UIAxe_raw_2, 'Z')
            app.UIAxe_raw_3 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_raw_3, 'Title')
            xlabel(app.UIAxe_raw_3, 'X')
            ylabel(app.UIAxe_raw_3, 'Y')
            zlabel(app.UIAxe_raw_3, 'Z')
            app.UIAxe_raw_4 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_raw_4, 'Title')
            xlabel(app.UIAxe_raw_4, 'X')
            ylabel(app.UIAxe_raw_4, 'Y')
            zlabel(app.UIAxe_raw_4, 'Z')
            app.UIAxe_raw_5 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_raw_5, 'Title')
            xlabel(app.UIAxe_raw_5, 'X')
            ylabel(app.UIAxe_raw_5, 'Y')
            zlabel(app.UIAxe_raw_5, 'Z')
            app.UIAxe_out_1 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_out_1, 'Title')
            xlabel(app.UIAxe_out_1, 'X')
            ylabel(app.UIAxe_out_1, 'Y')
            zlabel(app.UIAxe_out_1, 'Z')
            app.UIAxe_out_2 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_out_2, 'Title')
            xlabel(app.UIAxe_out_2, 'X')
            ylabel(app.UIAxe_out_2, 'Y')
            zlabel(app.UIAxe_out_2, 'Z')
            app.UIAxe_out_3 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_out_3, 'Title')
            xlabel(app.UIAxe_out_3, 'X')
            ylabel(app.UIAxe_out_3, 'Y')
            zlabel(app.UIAxe_out_3, 'Z')
            app.UIAxe_out_4 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_out_4, 'Title')
            xlabel(app.UIAxe_out_4, 'X')
            ylabel(app.UIAxe_out_4, 'Y')
            zlabel(app.UIAxe_out_4, 'Z')
            app.UIAxe_out_5 = uiaxes(app.MainUIFigure);
            title(app.UIAxe_out_5, 'Title')
            xlabel(app.UIAxe_out_5, 'X')
            ylabel(app.UIAxe_out_5, 'Y')
            zlabel(app.UIAxe_out_5, 'Z')
            app.importModel = uibutton(app.MainUIFigure, 'push');
            app.importModel.ButtonPushedFcn = createCallbackFcn(app, @importModelPushed, true);
            app.importModel.Text = 'Input Model';
            app.Label = uilabel(app.MainUIFigure);
            app.Label.Text = 'Selection Model';
            app.ModelList = uilistbox(app.MainUIFigure);
            app.ModelList.Items = {};
            app.ModelList.ValueChangedFcn = createCallbackFcn(app, @ModelListValueChanged, true);
            app.ModelList.Value = {};
            app.importTestData = uibutton(app.MainUIFigure, 'push');
            app.importTestData.ButtonPushedFcn = createCallbackFcn(app, @importTestDataButtonPushed, true);
            app.importTestData.Text = 'Input Test Data';
            app.DataVolumeLabel = uilabel(app.MainUIFigure);
            app.DataVolumeLabel.Text = 'Data Volume：';
            app.testDataNum = uilabel(app.MainUIFigure);
            app.testDataNum.Text = '0';
            app.CurrentLocationLabel = uilabel(app.MainUIFigure);
            app.CurrentLocationLabel.Text = 'Current Location：';
            app.testDataNumNow = uilabel(app.MainUIFigure);
            app.testDataNumNow.Text = '0';
            app.NextGroup = uibutton(app.MainUIFigure, 'push');
            app.NextGroup.ButtonPushedFcn = createCallbackFcn(app, @NextGroupButtonPushed, true);
            app.NextGroup.Text = 'Next Group';
            app.PrvGroup = uibutton(app.MainUIFigure, 'push');
            app.PrvGroup.ButtonPushedFcn = createCallbackFcn(app, @PrvGroupButtonPushed, true);
            app.PrvGroup.Text = 'Previous Group';
            app.LabelData = uilabel(app.MainUIFigure);
            app.LabelData.Text = 'Label Data';
            app.RawDataLabel = uilabel(app.MainUIFigure);
            app.RawDataLabel.Text = 'Raw Data';
            app.addSel_1 = uibutton(app.MainUIFigure, 'push');
            app.addSel_1.ButtonPushedFcn = createCallbackFcn(app, @addSel_1ButtonPushed, true);
            app.addSel_1.Text = 'Select';
            app.addSel_2 = uibutton(app.MainUIFigure, 'push');
            app.addSel_2.ButtonPushedFcn = createCallbackFcn(app, @addSel_2ButtonPushed, true);
            app.addSel_2.Text = 'Select';
            app.addSel_3 = uibutton(app.MainUIFigure, 'push');
            app.addSel_3.ButtonPushedFcn = createCallbackFcn(app, @addSel_3ButtonPushed, true);
            app.addSel_3.Text = 'Select';
            app.addSel_4 = uibutton(app.MainUIFigure, 'push');
            app.addSel_4.ButtonPushedFcn = createCallbackFcn(app, @addSel_4ButtonPushed, true);
            app.addSel_4.Text = 'Select';
            app.addSel_5 = uibutton(app.MainUIFigure, 'push');
            app.addSel_5.ButtonPushedFcn = createCallbackFcn(app, @addSel_5ButtonPushed, true);
            app.addSel_5.Text = 'Select';
            app.printImg = uibutton(app.MainUIFigure, 'push');
            app.printImg.ButtonPushedFcn = createCallbackFcn(app, @printImgButtonPushed, true);
            app.printImg.Text = 'Generate Pictures';
            app.Label_6 = uilabel(app.MainUIFigure);
            app.Label_6.Text = 'Selected Data';
            app.selList = uilistbox(app.MainUIFigure);
            app.selList.Items = {};
            app.selList.Value = {};
            app.clearModel = uibutton(app.MainUIFigure, 'push');
            app.clearModel.ButtonPushedFcn = createCallbackFcn(app, @clearModelButtonPushed, true);
            app.clearModel.Text = 'Delete';
            app.clearData = uibutton(app.MainUIFigure, 'push');
            app.clearData.Text = 'Delete';
            app.draw3D = uibutton(app.MainUIFigure, 'push');
            app.draw3D.ButtonPushedFcn = createCallbackFcn(app, @draw3DButtonPushed, true);
            app.draw3D.Text = 'Drawing';
            app.OutputDataLabel = uilabel(app.MainUIFigure);
            app.OutputDataLabel.Text = 'Output Data';
            app.selIndex = uibutton(app.MainUIFigure, 'push');
            app.selIndex.ButtonPushedFcn = createCallbackFcn(app, @selIndexButtonPushed, true);
            app.selIndex.Text = 'Location';
            app.clearMaker = uibutton(app.MainUIFigure, 'push');
            app.clearMaker.ButtonPushedFcn = createCallbackFcn(app, @clearMakerButtonPushed, true);
            app.clearMaker.Text = 'Delete Image';
            app.autoSel = uibutton(app.MainUIFigure, 'push');
            app.autoSel.ButtonPushedFcn = createCallbackFcn(app, @autoSelButtonPushed, true);
            app.autoSel.Text = 'Automatic Filter';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeskAPP_Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end